classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Subeunarchivoconlanubedepuntosdetumodelo3dLabel  matlab.ui.control.Label
        SubirarchivoButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        state
        grid
        plot_axis
        panel
        selector_label
        selector
        next
        previous
        radio_selector
        radio_label
        measureObject Object3D
    end

    methods (Access = private)
        
        function onNextPushed(app, ~)
            import utils.selectPoints
            import interpolate.InterpolateCurve

            if strcmp(app.state,'dropdown')
                app.previous.Enable = 'on';
                app.selector.Enable = 'off';

                % create selector 
                app.radio_selector = uibuttongroup(app.panel, "BackgroundColor", "White", "BorderType","none");
                app.radio_selector.Position = [60 465 490 200];
                app.radio_label = uilabel(app.radio_selector, "Text", "De que forma desea hacer la medicion?", "Position", [5 175 300 25], "FontSize",14);
                if strcmp(app.selector.Value,'Volumen')
                    app.state = 'volumen';
                    b1 = uiradiobutton(app.radio_selector, "Text", "Calcular el volumen de toda la figura",'Position',[5 150 300 25], "FontSize",14);
                    b2 = uiradiobutton(app.radio_selector, "Text", "Calcular el volumen de una region",'Position',[5 125 300 25], "FontSize",14);
                elseif strcmp(app.selector.Value,'Longitud')
                    app.state = 'longitud';
                    b1 = uiradiobutton(app.radio_selector, "Text", "Seleccionando los puntos manualmente",'Position',[5 150 300 25], "FontSize",14);
                    b2 = uiradiobutton(app.radio_selector, "Text", "Dibujando una curva sobre la figura",'Position',[5 125 300 25], "FontSize",14);
                elseif strcmp(app.selector.Value,'Area')
                    app.state = 'area';
                    b1 = uiradiobutton(app.radio_selector, "Text", "Seleccionando los puntos manualmente",'Position',[5 150 300 25], "FontSize",14);
                    b2 = uiradiobutton(app.radio_selector, "Text", "Dibujando una region sobre la figura",'Position',[5 125 300 25], "FontSize",14);
                    b3 = uiradiobutton(app.radio_selector, "Text", "Calcular el area de toda la figura",'Position',[5 100 300 25], "FontSize",14);
                end
            elseif strcmp(app.state, 'volumen')
                if strcmp(app.radio_selector.SelectedObject.Text, 'Calcular el volumen de toda la figura')

                elseif strcmp(app.radio_selector.SelectedObject.Text, 'Calcular el volumen de una region')

                end
            elseif strcmp(app.state, 'longitud')
                if strcmp(app.radio_selector.SelectedObject.Text, 'Seleccionando los puntos manualmente')
                    % IMPORTANTE
                    % - primero deberia preguntar si desea suavizar la
                    % curva que va a medir para saber si usar splines
                    % cubicos o lineales
                    deegre = 'cubic';
                    ans = uiconfirm(app.UIFigure, ...
                        "Selecciona los puntos haciendo click en ellos con el cursor en modo data-tip. Cuando hallas finalizado presiona cualquier tecla", ...
                        "Selecciona los puntos", ...
                        "Options","OK", "Icon","info");
                    focus(app.UIFigure)
                    nextHandle = selectPoints(app.UIFigure);
                    app.UIFigure.WindowButtonDownFcn = @(src,~)uiresume(ancestor(src,'figure')); % ERROR: ESTO DEJA DE FUNCIONAR CUANDO SE DA CLICK EN EL DATATIP
                    app.UIFigure.WindowKeyPressFcn = @(src,~)uiresume(ancestor(src,'figure'));

                    while true 
                        [points, ended] = nextHandle();
                        if ended
                            break
                        end
                        curve = InterpolateCurve(points); % ver si points son de size correcto
                        curve.show(app.plot_axis) % modificar: esto deberia poder recibir plot_axis
                        % no se si es necesario hold por lo que ya
                        % especifique el axis
                        % posible optimizacion: si spline es lineal al
                        % seleccionar un punto nuevo no es necesario
                        % calcular splines en todos los intervalos, solo en
                        % el nuevo
                    end
                    % length = app.measureObject.lengthCurve(curve, deegre); % modificar: para que reciba curve y no points_curve
                    % tambien se debe modificar en los tests
                    % lengthCurve podria incluir parametro verbose para que
                    % retorne string con el proceso explicado o no se

                    %cambiar el estado del app: mostrar proceso y resultados
                        
                elseif strcmp(app.radio_selector.SelectedObject.Text, 'Dibujando una curva sobre la figura')

                end
            elseif strcmp(app.state, 'area')
                if strcmp(app.radio_selector.SelectedObject.Text, 'Seleccionando los puntos manualmente')

                elseif strcmp(app.radio_selector.SelectedObject.Text, 'Dibujando una region sobre la figura')

                elseif strcmp(app.radio_selector.SelectedObject.Text, 'Calcular el area de toda la figura')

                end
            end
        end

        function onPreviousPushed(app, ~)
            if ismember(app.state, {'volumen', 'area', 'longitud'})
                delete(app.radio_selector.Children)
                delete(app.radio_selector)
                app.selector.Enable = 'on';
                app.next.Enable = 'on';
                app.previous.Enable = 'off';
                app.state = 'dropdown';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubirarchivoButton
        function SubirarchivoButtonPushed(app, event)
            % Clear any existing layout in the figure
            [file,location] = uigetfile("*.asc");
            drawnow;
            figure(app.UIFigure)
            if file == 0
                return
            end
            
            try
                app.measureObject = Object3D(strcat(location,file));
            catch ME
                uialert(app.UIFigure,ME.message,"No se pudo cargar el modelo 3D","Interpreter","html");
                return
            end
            delete(app.UIFigure.Children);
            
            % Create a new grid layout with two columns
            app.grid = uigridlayout(app.UIFigure, [1, 2]);
            app.grid.RowHeight = {'1x'}; % 1st row flexible, 2nd row fits content
            app.grid.ColumnWidth = {'2x', '1x'}; % Left column wider than right
            app.grid.ColumnSpacing = 10;
            app.grid.Padding = [50 50 50 50];
        
            % Add an axis to the left column
            app.plot_axis = uiaxes(app.grid);
            app.plot_axis.Layout.Row = 1;
            app.plot_axis.Layout.Column = 1;

            app.measureObject.plot(app.plot_axis)

            % panel
            app.panel = uipanel(app.grid, "BackgroundColor","white");
            app.panel.BorderType = 'line';
            app.panel.Layout.Row = 1;
            app.panel.Layout.Column = 2;

            
            % Selector label
            app.selector_label = uilabel(app.panel);
            app.selector_label.Text = "Seleccione la medida a realizar:";
            app.selector_label.FontSize = 14;
            app.selector_label.Position = [60 725 490 30];
        
            % Add a dropdown to the right column, first row
            app.selector = uidropdown(app.panel);
            app.selector.Items = {'Volumen', 'Area', 'Longitud'};
            app.selector.Position = [60 695 490 30];

            % Add 'Next' button
            app.next = uibutton(app.panel, 'push');
            app.next.Text = 'Siguiente';
            app.next.Position = [310 100 230 30];
            app.next.ButtonPushedFcn = createCallbackFcn(app, @onNextPushed, true);
             
            % % Add 'Previous' button
            app.previous = uibutton(app.panel, 'push');
            app.previous.Text = 'Anterior';
            app.previous.Position = [60 100 230 30];
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @onPreviousPushed, true);
            app.previous.Enable = 'off';
            app.state = 'dropdown';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubirarchivoButton = uibutton(app.UIFigure, 'push');
            app.SubirarchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SubirarchivoButtonPushed, true);
            app.SubirarchivoButton.Text = 'Subir archivo';
            app.Subeunarchivoconlanubedepuntosdetumodelo3dLabel = uilabel(app.UIFigure);
            app.Subeunarchivoconlanubedepuntosdetumodelo3dLabel.Text = 'Sube un archivo con la nube de puntos de tu modelo 3d';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end